classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GenerateMatrixButton           matlab.ui.control.Button
        RowofMatrixKnobLabel           matlab.ui.control.Label
        RowofMatrixKnob                matlab.ui.control.DiscreteKnob
        ColumnofMatrixKnobLabel        matlab.ui.control.Label
        ColumnofMatrixKnob             matlab.ui.control.DiscreteKnob
        ButtonGroup                    matlab.ui.container.ButtonGroup
        KnobsButton                    matlab.ui.control.RadioButton
        ManuelButton                   matlab.ui.control.RadioButton
        RowSpinnerLabel                matlab.ui.control.Label
        RowSpinner                     matlab.ui.control.Spinner
        ColumnSpinnerLabel             matlab.ui.control.Label
        ColumnSpinner                  matlab.ui.control.Spinner
        Knob_6                         matlab.ui.control.DiscreteKnob
        Knob_8                         matlab.ui.control.DiscreteKnob
        Image                          matlab.ui.control.Image
        Label                          matlab.ui.control.Label
        Switch                         matlab.ui.control.RockerSwitch
        IMAGEPROCESSINGHOMEWORK1Label  matlab.ui.control.Label
        HalimezgeKABAK180403001Label   matlab.ui.control.Label
        EnterButton                    matlab.ui.control.Button
        EditField2                     matlab.ui.control.EditField
        Lamp                           matlab.ui.control.Lamp
        Panel_2                        matlab.ui.container.Panel
        Lamp_2                         matlab.ui.control.Lamp
        Knob_4                         matlab.ui.control.Knob
        Knob_5                         matlab.ui.control.Knob
        XValueSpinnerLabel             matlab.ui.control.Label
        XValueSpinner                  matlab.ui.control.Spinner
        YValueSpinnerLabel             matlab.ui.control.Label
        YValueSpinner                  matlab.ui.control.Spinner
        reflect                        matlab.ui.control.Button
        CropButton                     matlab.ui.control.Button
        ResizeButton                   matlab.ui.control.Button
        ShiftButton                    matlab.ui.control.Button
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        VerticalButton                 matlab.ui.control.RadioButton
        HorizontalButton               matlab.ui.control.RadioButton
        Knob_9                         matlab.ui.control.DiscreteKnob
        Label_2                        matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        filterbound1;  % Description
        filterbound2; 
        image1;
        image2;
        c;
        image3;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
             if value==0
                app.Image.Visible='On';
                app.UIAxes.Visible='Off';
             else
                app.Image.Visible='Off';
                app.UIAxes.Visible='On';
            end
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           app.image1 = imread(fullname);
           imshow(app.image1,'Parent',app.UIAxes);
%             value=app.EnterTheImagesNameEditField.Value;
%             app.image1=imread(value);
%             imshow(app.image1,'Parent',app.UIAxes)
            app.UIAxes.XLim=[0 width(app.image1)];
           app.UIAxes.YLim=[0 height(app.image1)];
            [~,~,app.c]=size(app.image1);
            if app.c==1
                text1 = sprintf('The Image Is Gray');
                app.Lamp.Color=[0.65 0.65 0.65];
                app.EditField2.Value=text1;
            end
            if    app.c==3
                text1 = sprintf('The Image Is Colourfull');
                c1=app.image1(100,100,1);
                c2=app.image1(100,100,2);
                c3=app.image1(100,100,3);
                app.Lamp.Color=[c1 c2 c3];
                app.EditField2.Value=text1;
            end
           
        end

        % Value changed function: Knob_6
        function Knob_6ValueChanged(app, event)
            value = app.Knob_6.Value;
            if value==0
                imshow(app.image1,'Parent',app.UIAxes)


            elseif value==1
                    gray=rgb2gray(app.image1);
                    imshow(gray,'Parent',app.UIAxes)
           
            elseif value==2
                bw=im2bw(app.image1);
                imshow(bw,'Parent',app.UIAxes)
            elseif value==3
                ng=imadjust(app.image1,[0,1],[1,0]);
                imshow(ng,'Parent',app.UIAxes)
            end
        end

        % Value changed function: Knob_4
        function Knob_4ValueChanged(app, event)
            a =app.Knob_4.Value;
             b=app.Knob_5.Value;
            c1=app.image1(a,b,1);
            c2=app.image1(a,b,2);
            c3=app.image1(a,b,3);
            app.Lamp_2.Color=[c1 c2 c3];
        end

        % Button pushed function: GenerateMatrixButton
        function GenerateMatrixButtonPushed(app, event)
            imshow(app.image1,'Parent',app.UIAxes)
            if app.KnobsButton.Value==1
                value =app.RowofMatrixKnob.Value;
                value2 =app.ColumnofMatrixKnob.Value;
            else
                value=app.RowSpinner.Value;
                value2=app.ColumnSpinner.Value;
            end
            app.filterbound1=value;
            app.filterbound2=value2;
            
           
        end

        % Value changed function: Knob_8
        function Knob_8ValueChanged(app, event)
            change = app.Knob_8.Value;
          if change==1
            filter=ones(app.filterbound1,app.filterbound2);
            filter=double(filter/sum(filter(:)));
            if app.c==1
                app.image2=im2double(app.image1);
                output=zeros((size(app.image2)));
                endpoint=size(app.image2,1)-app.filterbound1-1;
                endpoint2=size(app.image2,2)-app.filterbound2-1;
            for i=1:endpoint
                for j=1:endpoint2
                    filtering1=app.image2(i:i+(app.filterbound1-1),j:j+(app.filterbound2-1)).*filter;
                    output(i,j)=sum(filtering1(:));
                end
            end
            
            for x=1:size(app.image2,1)
                for y=1:size(app.image2,2)
                 if output(x,y)==0
                       output(x,y)=app.image2(x,y);         
                 end
                end
            end
            imshow(output,'Parent',app.UIAxes)
            end
            if app.c==3
            
            r=im2double(app.image1(:,:,1));
            g=im2double(app.image1(:,:,2));
            b=im2double(app.image1(:,:,3));
            
            finak=zeros((size(r)));
            final2=zeros((size(r)));
            final3=zeros((size(r)));
            endpoint=size(r,1)-app.filterbound1-1;
            endpoint2=size(r,2)-app.filterbound2-1;
            for i=1:endpoint
                for j=1:endpoint2
                    filtering1=r(i:i+(app.filterbound1-1),j:j+(app.filterbound2-1)).*filter;
                    finak(i,j)=sum(filtering1(:));
                    filtering2=g(i:i+(app.filterbound1-1),j:j+(app.filterbound2-1)).*filter;
                    final2(i,j)=sum(filtering2(:));
                    filtering3=b(i:i+(app.filterbound1-1),j:j+(app.filterbound2-1)).*filter;
                    final3(i,j)=sum(filtering3(:));
                end
            end
            
            for x=1:size(r,1)
                for y=1:size(r,2)
                 if finak(x,y)==0
                       finak(x,y)=r(x,y);         
                 end
                 if final2(x,y)==0
                      final2(x,y)=g(x,y);
                 end
                 if final3(x,y)==0
                     final3(x,y)=b(x,y);
                 end
                end
            end
            
             
            end
            app.image2=cat(3,finak,final2,final3);
            imshow(app.image2,'Parent',app.UIAxes)
            
       elseif change==2
                a=app.filterbound1;
                a2=app.filterbound2;
                [s1,s2,~]=size(app.image1);
            r=im2double(app.image1(:,:,1));
            g=im2double(app.image1(:,:,2));
            b=im2double(app.image1(:,:,3));
               filter=ones(a,a2);
    filter=double(filter/sum(filter(:)));
    finak=zeros((size(r)));
    final2=zeros((size(r)));
    final3=zeros((size(r)));
    endpoint=size(r,1);
    endpoint2=size(r,2);
    extra=rem(s1,a);
    extra2=rem(s2,a2);
    for i=1:a:endpoint-extra
        for j=1:a2:endpoint2-extra2
           
            filtering1=r(i:i+(a-1),j:j+(a2-1)).*filter;
            for z=1:a
                for z2=1:a2
                  finak(i+z-1,j+z2-1)=sum(filtering1(:));
                end
            end
            filtering2=g(i:i+(a-1),j:j+(a2-1)).*filter;
            for z=1:a
                for z2=1:a2
                   final2(i+z-1,j+z2-1)=sum(filtering2(:));
                end
            end
            filtering3=b(i:i+(a-1),j:j+(a2-1)).*filter;
            for z=1:a
                for z2=1:a2
                  final3(i+z-1,j+z2-1)=sum(filtering3(:));
                end
            end
        end
    end
    a3=extra;
    a4=a2;
    filter2=ones(a3,a4);
    filter2=double(filter2/sum(filter2(:)));
    if extra~=0
       if extra~=1
           for i=endpoint-extra:a3:endpoint-a3+1
            for j=1:a4:endpoint2-a4+1
                filtering1=r(i:i+(a3-1),j:j+(a4-1)).*filter2;
            for z=1:a3
                for z2=1:a4
                  finak(i+z-1,j+z2-1)=sum(filtering1(:));
                end
            end
            filtering2=g(i:i+(a3-1),j:j+(a4-1)).*filter2;
            for z=1:a3
                for z2=1:a4
                   final2(i+z-1,j+z2-1)=sum(filtering2(:));
                end
            end
            filtering3=b(i:i+(a3-1),j:j+(a4-1)).*filter2;
            for z=1:a3
                for z2=1:a4
                  final3(i+z-1,j+z2-1)=sum(filtering3(:));
                end
            end
            end
           
                
           end
       end
    end


    a5=a;
    a6=extra2;
    filter3=ones(a5,a6);
    filter3=double(filter3/sum(filter3(:)));
    
    if extra2~=0
       if extra2~=1
           for i=1:a5:endpoint-a5+1
            for j=endpoint2-extra2:a6:endpoint2-a6+1
                filtering1=r(i:i+(a5-1),j:j+(a6-1)).*filter3;
            for z=1:a5
                for z2=1:a6
                  finak(i+z-1,j+z2-1)=sum(filtering1(:));
                end
            end
            filtering2=g(i:i+(a5-1),j:j+(a6-1)).*filter3;
            for z=1:a5
                for z2=1:a6
                   final2(i+z-1,j+z2-1)=sum(filtering2(:));
                end
            end
            filtering3=b(i:i+(a5-1),j:j+(a6-1)).*filter3;
            for z=1:a5
                for z2=1:a6
                  final3(i+z-1,j+z2-1)=sum(filtering3(:));
                end
            end
            end
           
                
           end
       end
    end
     if extra~=0 && extra2~=0
         if extra~=1 && extra2~=1
            for i=endpoint-extra+1:extra:endpoint
                for j=endpoint2-extra2+1:extra2:endpoint2
                    a=extra;
                    a2=extra2;
                    filter=ones(a,a2);
                    filter=double(filter/sum(filter(:)));
    
                    filtering1=r(i:i+(a-1),j:j+(a2-1)).*filter;
            for z=1:a
                for z2=1:a2
                  finak(i+z-1,j+z2-1)=sum(filtering1(:));
                end
            end
            filtering2=g(i:i+(a-1),j:j+(a2-1)).*filter;
            for z=1:a
                for z2=1:a2
                   final2(i+z-1,j+z2-1)=sum(filtering2(:));
                end
            end
            filtering3=b(i:i+(a-1),j:j+(a2-1)).*filter;
            for z=1:a
                for z2=1:a2
                  final3(i+z-1,j+z2-1)=sum(filtering3(:));
                end
            end
                    
                end
            end
         end
     end
     app.image2=cat(3,finak,final2,final3);
     imshow(app.image2,'Parent',app.UIAxes)
          elseif change==0
              app.image2=app.image1;
              imshow(app.image2,'Parent',app.UIAxes)
         end
            
        end

        % Button pushed function: reflect
        function reflectButtonPushed(app, event)
            r=im2double(app.image1(:,:,1));
            g=im2double(app.image1(:,:,2));
            b=im2double(app.image1(:,:,3));
            if app.HorizontalButton.Value==1
            
            ref1=r(end:-1:1,:);
            ref2=g(end:-1:1,:);
            ref3=b(end:-1:1,:);
            app.image2=cat(3,ref1,ref2,ref3);
            elseif app.VerticalButton.Value==1
                ref1=r(:,end:-1:1);
                ref2=g(:,end:-1:1);
                ref3=b(:,end:-1:1);
                app.image2=cat(3,ref1,ref2,ref3);
            end
            imshow(app.image2,'Parent',app.UIAxes)
        end

        % Button pushed function: ShiftButton
        function ShiftButtonPushed(app, event)
            x=app.XValueSpinner.Value;
            y=app.YValueSpinner.Value;
            [a,s,~]=size(app.image1);
            B1=zeros(a,s); 
            B2=zeros(a,s); 
            B3=zeros(a,s); 
            r=im2double(app.image1(:,:,1));
            g=im2double(app.image1(:,:,2));
            b=im2double(app.image1(:,:,3));
            for i=x:a
            for j=y:s 
                B1(i,j)=r(i-x+1,j-y+1); 
                B2(i,j)=g(i-x+1,j-y+1); 
                B3(i,j)=b(i-x+1,j-y+1); 
            end 
            end 
             app.image2=cat(3,B1,B2,B3);
             imshow(app.image2,'Parent',app.UIAxes)
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            x=app.XValueSpinner.Value;
            y=app.YValueSpinner.Value;
            r=im2double(app.image1(:,:,1));
            g=im2double(app.image1(:,:,2));
            b=im2double(app.image1(:,:,3));
            k1 = r(x:y, x:y);
            k2 = g(x:y, x:y);
            k3 = b(x:y, x:y);
            app.image2=cat(3,k1,k2,k3);
            imshow(app.image2,'Parent',app.UIAxes)
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
             x=app.XValueSpinner.Value;
            y=app.YValueSpinner.Value;
            r=im2double(app.image1(:,:,1));
            g=im2double(app.image1(:,:,2));
            b=im2double(app.image1(:,:,3));
             res1=r(1:x:end,1:y:end);
             res2=g(1:x:end,1:y:end);
             res3=b(1:x:end,1:y:end);
             app.image2=cat(3,res1,res2,res3);
            imshow(app.image2,'Parent',app.UIAxes)
        end

        % Value changed function: Knob_9
        function Knob_9ValueChanged(app, event)
            value = app.Knob_9.Value;
            img=app.image1;
            if value==1
                
                YIQ=uint8(zeros(size(img)));
                for i=1:size(img,1)
                  for j=1:size(img,2)
                    YIQ(i,j,1)=0.2989*img(i,j,1)+0.5870*img(i,j,2)+0.1140*img(i,j,3);
                    YIQ(i,j,2)=0.596*img(i,j,1)-0.274*img(i,j,2)-0.322*img(i,j,3);
                    YIQ(i,j,3)=0.211*img(i,j,1)-0.523*img(i,j,2)+0.312*img(i,j,3);
                  end
                end
                app.image2=YIQ;
                imshow(app.image2,'Parent',app.UIAxes)
            elseif value==2
                img=double(img)/255;
                r2=img(:,:,1);
                g2=img(:,:,2);
                b2=img(:,:,3);
                numi=1/2*((r2-g2)+(r2-b2));
                denom=((r2-g2).^2+((r2-b2).*(g2-b2))).^0.5;
                H=acosd(numi./(denom+0.000001));
                
                %If B>G then H= 360-Theta
                H(b2>g2)=360-H(b2>g2);
                
                %Normalize to the range [0 1]
                H=H/360;
                
                %Saturation
                S=1- (3./(sum(img,3)+0.000001)).*min(img,[],3);
                
                
                %Intensity
                I=sum(img,3)./3;
                HSI=zeros(size(img));
                HSI(:,:,1)=H;
                HSI(:,:,2)=S;
                HSI(:,:,3)=I;
                app.image2=HSI;
                imshow(app.image2,'Parent',app.UIAxes)
            elseif value==0
                app.image2=app.image1;
                 imshow(app.image2,'Parent',app.UIAxes)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GenerateMatrixButton = uibutton(app.Panel, 'push');
            app.GenerateMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMatrixButtonPushed, true);
            app.GenerateMatrixButton.Text = 'Generate Matrix';
            app.RowofMatrixKnobLabel = uilabel(app.Panel);
            app.RowofMatrixKnobLabel.Text = 'Row of Matrix';
            app.RowofMatrixKnob = uiknob(app.Panel, 'discrete');
            app.RowofMatrixKnob.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30'};
            app.RowofMatrixKnob.ItemsData = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.RowofMatrixKnob.Value = 0;
            app.ColumnofMatrixKnobLabel = uilabel(app.Panel);
            app.ColumnofMatrixKnobLabel.Text = 'Column of Matrix';
            app.ColumnofMatrixKnob = uiknob(app.Panel, 'discrete');
            app.ColumnofMatrixKnob.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30'};
            app.ColumnofMatrixKnob.ItemsData = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.ColumnofMatrixKnob.Value = 0;
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.KnobsButton = uiradiobutton(app.ButtonGroup);
            app.KnobsButton.Text = 'Knobs';
            app.KnobsButton.Value = true;
            app.ManuelButton = uiradiobutton(app.ButtonGroup);
            app.ManuelButton.Text = 'Manuel';
            app.RowSpinnerLabel = uilabel(app.Panel);
            app.RowSpinnerLabel.Text = 'Row';
            app.RowSpinner = uispinner(app.Panel);
            app.ColumnSpinnerLabel = uilabel(app.Panel);
            app.ColumnSpinnerLabel.Text = 'Column';
            app.ColumnSpinner = uispinner(app.Panel);
            app.Knob_6 = uiknob(app.Panel, 'discrete');
            app.Knob_6.Items = {'Off', 'Gray', 'Black-White', 'Negative'};
            app.Knob_6.ItemsData = [0 1 2 3];
            app.Knob_6.ValueChangedFcn = createCallbackFcn(app, @Knob_6ValueChanged, true);
            app.Knob_6.Value = 0;
            app.Knob_8 = uiknob(app.Panel, 'discrete');
            app.Knob_8.Items = {'Off', 'Blur', 'Mosaic'};
            app.Knob_8.ItemsData = [0 1 2 3];
            app.Knob_8.ValueChangedFcn = createCallbackFcn(app, @Knob_8ValueChanged, true);
            app.Knob_8.Value = 0;
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'giphy.gif';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.Switch = uiswitch(app.GridLayout, 'rocker');
            app.Switch.Items = {'Invisible', 'Visible'};
            app.Switch.ItemsData = [0 1];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 0;
            app.IMAGEPROCESSINGHOMEWORK1Label = uilabel(app.GridLayout);
            app.IMAGEPROCESSINGHOMEWORK1Label.Text = 'IMAGE PROCESSING HOMEWORK 1';
            app.HalimezgeKABAK180403001Label = uilabel(app.GridLayout);
            app.HalimezgeKABAK180403001Label.Text = 'Halime Özge KABAK 180403001';
            app.EnterButton = uibutton(app.GridLayout, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.EditField2 = uieditfield(app.GridLayout, 'text');
            app.Lamp = uilamp(app.GridLayout);
            app.Panel_2 = uipanel(app.GridLayout);
            app.Lamp_2 = uilamp(app.Panel_2);
            app.Knob_4 = uiknob(app.Panel_2, 'continuous');
            app.Knob_4.Limits = [0 255];
            app.Knob_4.MajorTicks = [0 50 100 150 200 255];
            app.Knob_4.ValueChangedFcn = createCallbackFcn(app, @Knob_4ValueChanged, true);
            app.Knob_5 = uiknob(app.Panel_2, 'continuous');
            app.Knob_5.Limits = [0 255];
            app.Knob_5.MajorTicks = [0 50 100 150 200 255];
            app.XValueSpinnerLabel = uilabel(app.Panel_2);
            app.XValueSpinnerLabel.Text = 'X-Value';
            app.XValueSpinner = uispinner(app.Panel_2);
            app.YValueSpinnerLabel = uilabel(app.Panel_2);
            app.YValueSpinnerLabel.Text = 'Y-Value';
            app.YValueSpinner = uispinner(app.Panel_2);
            app.reflect = uibutton(app.Panel_2, 'push');
            app.reflect.ButtonPushedFcn = createCallbackFcn(app, @reflectButtonPushed, true);
            app.reflect.Text = 'Reflect';
            app.CropButton = uibutton(app.Panel_2, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.ResizeButton = uibutton(app.Panel_2, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.ShiftButton = uibutton(app.Panel_2, 'push');
            app.ShiftButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftButtonPushed, true);
            app.ShiftButton.Text = 'Shift';
            app.ButtonGroup_2 = uibuttongroup(app.Panel_2);
            app.VerticalButton = uiradiobutton(app.ButtonGroup_2);
            app.VerticalButton.Text = 'Vertical';
            app.VerticalButton.Value = true;
            app.HorizontalButton = uiradiobutton(app.ButtonGroup_2);
            app.HorizontalButton.Text = 'Horizontal';
            app.Knob_9 = uiknob(app.Panel_2, 'discrete');
            app.Knob_9.Items = {'RGB', 'YIQ', 'HSI'};
            app.Knob_9.ItemsData = [0 1 2 3];
            app.Knob_9.ValueChangedFcn = createCallbackFcn(app, @Knob_9ValueChanged, true);
            app.Knob_9.Value = 0;
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end