classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HOKAppUIFigure                matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        Panel                         matlab.ui.container.Panel
        GenerateMatrixButton          matlab.ui.control.Button
        RowofMatrixKnobLabel          matlab.ui.control.Label
        RowofMatrixKnob               matlab.ui.control.DiscreteKnob
        ColumnofMatrixKnobLabel       matlab.ui.control.Label
        ColumnofMatrixKnob            matlab.ui.control.DiscreteKnob
        ButtonGroup                   matlab.ui.container.ButtonGroup
        KnobsButton                   matlab.ui.control.RadioButton
        ManuelButton                  matlab.ui.control.RadioButton
        RowSpinnerLabel               matlab.ui.control.Label
        RowSpinner                    matlab.ui.control.Spinner
        ColumnSpinnerLabel            matlab.ui.control.Label
        ColumnSpinner                 matlab.ui.control.Spinner
        Knob_8                        matlab.ui.control.DiscreteKnob
        DropDown                      matlab.ui.control.DropDown
        ShowHistogramButton           matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        Label                         matlab.ui.control.Label
        Switch                        matlab.ui.control.RockerSwitch
        IMAGEPROCESSINGLabel          matlab.ui.control.Label
        HalimezgeKABAK180403001Label  matlab.ui.control.Label
        EnterButton                   matlab.ui.control.Button
        EditField2                    matlab.ui.control.EditField
        Lamp                          matlab.ui.control.Lamp
        Panel_2                       matlab.ui.container.Panel
        XValueSpinnerLabel            matlab.ui.control.Label
        XValueSpinner                 matlab.ui.control.Spinner
        YValueSpinnerLabel            matlab.ui.control.Label
        YValueSpinner                 matlab.ui.control.Spinner
        reflect                       matlab.ui.control.Button
        CropButton                    matlab.ui.control.Button
        ResizeButton                  matlab.ui.control.Button
        ShiftButton                   matlab.ui.control.Button
        ButtonGroup_2                 matlab.ui.container.ButtonGroup
        VerticalButton                matlab.ui.control.RadioButton
        HorizontalButton              matlab.ui.control.RadioButton
        Spinner_2                     matlab.ui.control.Spinner
        Spinner_3                     matlab.ui.control.Spinner
        WhiteSliderLabel              matlab.ui.control.Label
        WhiteSlider                   matlab.ui.control.Slider
        BlackSliderLabel              matlab.ui.control.Label
        BlackSlider                   matlab.ui.control.Slider
        ListBox                       matlab.ui.control.ListBox
        CalculateSNRButton            matlab.ui.control.Button
        EditField                     matlab.ui.control.NumericEditField
        Knob_11                       matlab.ui.control.DiscreteKnob
        Knob_10                       matlab.ui.control.DiscreteKnob
        Knob_9                        matlab.ui.control.DiscreteKnob
        ButtonGroup_3                 matlab.ui.container.ButtonGroup
        DiamondButton                 matlab.ui.control.ToggleButton
        DiskButton                    matlab.ui.control.ToggleButton
        OctagonButton                 matlab.ui.control.ToggleButton
        LinearButton                  matlab.ui.control.ToggleButton
        RectangleButton               matlab.ui.control.ToggleButton
        SquareButton                  matlab.ui.control.ToggleButton
        MatrixwithonesButton          matlab.ui.control.ToggleButton
        Spinner_4                     matlab.ui.control.Spinner
        Spinner_6                     matlab.ui.control.Spinner
        Label_3                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        DONEButton                    matlab.ui.control.Button
        OriginalImageCheckBox         matlab.ui.control.CheckBox
        Label_2                       matlab.ui.control.Label
        HomeworksButton               matlab.ui.control.Button
        FilteringButton               matlab.ui.control.Button
        Filtering2Button              matlab.ui.control.Button
        DropDown_2                    matlab.ui.control.DropDown
        InverseFilterButton           matlab.ui.control.Button
        OTSUThresholdButton           matlab.ui.control.Button
        RegionGrowingButton           matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        filterbound1;  % Description
        filterbound2; 
        image1;
        image2;
        c;
        image3;
        image_c;
        image_e;
        image_f;
        image_g;
        SE;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %appdesigner("homeworks.mlapp");
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
             if value==0
                app.Image.Visible='On';
                app.UIAxes.Visible='Off';
             else
                app.Image.Visible='Off';
                app.UIAxes.Visible='On';
            end
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           app.image1 = imread(fullname);
           imshow(app.image1,'Parent',app.UIAxes);
%             value=app.EnterTheImagesNameEditField.Value;
%             app.image1=imread(value);
%             imshow(app.image1,'Parent',app.UIAxes)
            app.UIAxes.XLim=[0 width(app.image1)];
           app.UIAxes.YLim=[0 height(app.image1)];
            [~,~,app.c]=size(app.image1);
            if app.c==1
                text1 = sprintf('The Image Is Gray');
                app.Lamp.Color=[0.65 0.65 0.65];
                app.EditField2.Value=text1;
            end
            if    app.c==3
                text1 = sprintf('The Image Is RGB');
                c1=app.image1(100,100,1);
                c2=app.image1(100,100,2);
                c3=app.image1(100,100,3);
                app.Lamp.Color=[c1 c2 c3];
                app.EditField2.Value=text1;
            end
           app.image2=app.image1;
        end

        % Value changed function: Knob_10
        function Knob_6ValueChanged(app, event)
            value = app.Knob_10.Value;
            if value==0
                imshow(app.image1,'Parent',app.UIAxes)


            elseif value==1
                    gray=rgb2gray(app.image1);
                    imshow(gray,'Parent',app.UIAxes)
           
            elseif value==2
                bw=im2bw(app.image1);
                imshow(bw,'Parent',app.UIAxes)
            elseif value==3
                ng=imadjust(app.image1,[0,1],[1,0]);
                imshow(ng,'Parent',app.UIAxes)
            end
        end

        % Callback function
        function Knob_4ValueChanged(app, event)
            a =app.Knob_4.Value;
             b=app.Knob_5.Value;
            c1=app.image1(a,b,1);
            c2=app.image1(a,b,2);
            c3=app.image1(a,b,3);
            app.Lamp_2.Color=[c1 c2 c3];
        end

        % Button pushed function: GenerateMatrixButton
        function GenerateMatrixButtonPushed(app, event)
            imshow(app.image1,'Parent',app.UIAxes)
            if app.KnobsButton.Value==1
                value =app.RowofMatrixKnob.Value;
                value2 =app.ColumnofMatrixKnob.Value;
            else
                value=app.RowSpinner.Value;
                value2=app.ColumnSpinner.Value;
            end
            app.filterbound1=value;
            app.filterbound2=value2;
            
           
        end

        % Button pushed function: reflect
        function reflectButtonPushed(app, event)
            r=im2double(app.image1(:,:,1));
            g=im2double(app.image1(:,:,2));
            b=im2double(app.image1(:,:,3));
            if app.HorizontalButton.Value==1
            
            ref1=r(end:-1:1,:);
            ref2=g(end:-1:1,:);
            ref3=b(end:-1:1,:);
            app.image2=cat(3,ref1,ref2,ref3);
            elseif app.VerticalButton.Value==1
                ref1=r(:,end:-1:1);
                ref2=g(:,end:-1:1);
                ref3=b(:,end:-1:1);
                app.image2=cat(3,ref1,ref2,ref3);
            end
            imshow(app.image2,'Parent',app.UIAxes)
        end

        % Button pushed function: ShiftButton
        function ShiftButtonPushed(app, event)
            x=app.XValueSpinner.Value;
            y=app.YValueSpinner.Value;
            [a,s,~]=size(app.image1);
            B1=zeros(a,s); 
            B2=zeros(a,s); 
            B3=zeros(a,s); 
            r=im2double(app.image1(:,:,1));
            g=im2double(app.image1(:,:,2));
            b=im2double(app.image1(:,:,3));
            for i=x:a
            for j=y:s 
                B1(i,j)=r(i-x+1,j-y+1); 
                B2(i,j)=g(i-x+1,j-y+1); 
                B3(i,j)=b(i-x+1,j-y+1); 
            end 
            end 
             app.image2=cat(3,B1,B2,B3);
             imshow(app.image2,'Parent',app.UIAxes)
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            x=(app.XValueSpinner.Value);
            y=(app.YValueSpinner.Value);
            x1=(app.Spinner_3.Value);
            y1=(app.Spinner_2.Value);
           app.image2=app.image1(x:x1,y:y1,:);
            
            imshow(app.image2,'Parent',app.UIAxes)
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
             x=app.XValueSpinner.Value;
            y=app.YValueSpinner.Value;
            app.image2=bilinearInterpolation(app.image1,[x,y]);
            imshow(app.image2,'Parent',app.UIAxes)
        end

        % Value changed function: Knob_9
        function Knob_9ValueChanged(app, event)
            value = app.Knob_9.Value;
            img=app.image1;
            if value==1
                
                YIQ=uint8(zeros(size(img)));
                for i=1:size(img,1)
                  for j=1:size(img,2)
                    YIQ(i,j,1)=0.2989*img(i,j,1)+0.5870*img(i,j,2)+0.1140*img(i,j,3);
                    YIQ(i,j,2)=0.596*img(i,j,1)-0.274*img(i,j,2)-0.322*img(i,j,3);
                    YIQ(i,j,3)=0.211*img(i,j,1)-0.523*img(i,j,2)+0.312*img(i,j,3);
                  end
                end
                app.image2=YIQ;
                imshow(app.image2,'Parent',app.UIAxes)
            elseif value==2
                img=double(img)/255;
                r2=img(:,:,1);
                g2=img(:,:,2);
                b2=img(:,:,3);
                numi=1/2*((r2-g2)+(r2-b2));
                denom=((r2-g2).^2+((r2-b2).*(g2-b2))).^0.5;
                H=acosd(numi./(denom+0.000001));
                
                %If B>G then H= 360-Theta
                H(b2>g2)=360-H(b2>g2);
                
                %Normalize to the range [0 1]
                H=H/360;
                
                %Saturation
                S=1- (3./(sum(img,3)+0.000001)).*min(img,[],3);
                
                
                %Intensity
                I=sum(img,3)./3;
                HSI=zeros(size(img));
                HSI(:,:,1)=H;
                HSI(:,:,2)=S;
                HSI(:,:,3)=I;
                app.image2=HSI;
                imshow(app.image2,'Parent',app.UIAxes)
          
            elseif value==0
                app.image2=app.image1;
                 imshow(app.image2,'Parent',app.UIAxes)
            end
        end

        % Value changed function: Knob_8
        function Knob_8ValueChanged2(app, event)
            change = app.Knob_8.Value;
          if change==1
              if app.c==1
                  [a,b]=size(app.image1);
                %Mean Filter
                f1=app.filterbound1;
                f2=app.filterbound2;
                filter=ones(f1,f2);
                filter=(filter)/sum(filter(:));
                filter=double(filter);
                array=padarray(app.image2,[app.filterbound1-2, app.filterbound2-2]);
                array=double(array);
                output1=zeros(a,b);
                for i=1:size(array,1)-f1-1
                    for j=1:size(array,2)-f2-1
                    temp1=array(i:i+f1-1,j:j+f2-1).*filter;
                    output1(i,j)=sum(temp1(:));
                    end
                end
                app.image2=(output1);
                imshow(app.image2,'Parent',app.UIAxes)
              end
              if app.c==3
                  [a,a2]=size(app.image1);
                %Mean Filter
                f1=app.filterbound1;
                f2=app.filterbound2;
                filter=ones(f1,f2);
                filter=(filter)/sum(filter(:));
                filter=double(filter);
                r=im2double(app.image2(:,:,1));
                g=im2double(app.image2(:,:,2));
                b=im2double(app.image2(:,:,3));
                array=padarray(r,[app.filterbound1-2 app.filterbound2-2]);
                array=double(array);
                array2=padarray(g,[app.filterbound1-2 app.filterbound2-2]);
                array2=double(array2);
                array3=padarray(b,[app.filterbound1-2 app.filterbound2-2]);
                array3=double(array3);
                output1=zeros(a,a2);
                output2=zeros(a,a2);
                output3=zeros(a,a2);
                for i=1:size(array,1)-f1-1
                    for j=1:size(array,2)-f2-1
                    temp1=array(i:i+f1-1,j:j+f2-1).*filter;
                    output1(i,j)=sum(temp1(:));
                    temp2=array2(i:i+f1-1,j:j+f2-1).*filter;
                    output2(i,j)=sum(temp2(:));
                    temp3=array3(i:i+f1-1,j:j+f2-1).*filter;
                    output3(i,j)=sum(temp3(:));
                    end
                end
                app.image2=cat(3,output1,output2,output3);
                imshow(app.image2,'Parent',app.UIAxes)
              end


%             filter=ones(app.filterbound1,app.filterbound2);
%             filter=double(filter/sum(filter(:)));
%             if app.c==1
%                 app.image2=im2double(app.image1);
%                 output=zeros((size(app.image2)));
%                 endpoint=size(app.image2,1)-app.filterbound1-1;
%                 endpoint2=size(app.image2,2)-app.filterbound2-1;
%             for i=1:endpoint
%                 for j=1:endpoint2
%                     filtering1=app.image2(i:i+(app.filterbound1-1),j:j+(app.filterbound2-1)).*filter;
%                     output(i,j)=sum(filtering1(:));
%                 end
%             end
%             
%             for x=1:size(app.image2,1)
%                 for y=1:size(app.image2,2)
%                  if output(x,y)==0
%                        output(x,y)=app.image2(x,y);         
%                  end
%                 end
%             end
%             imshow(output,'Parent',app.UIAxes)
%             end
%             if app.c==3
%             
%             r=im2double(app.image1(:,:,1));
%             g=im2double(app.image1(:,:,2));
%             b=im2double(app.image1(:,:,3));
%             
%             finak=zeros((size(r)));
%             final2=zeros((size(r)));
%             final3=zeros((size(r)));
%             endpoint=size(r,1)-app.filterbound1-1;
%             endpoint2=size(r,2)-app.filterbound2-1;
%             for i=1:endpoint
%                 for j=1:endpoint2
%                     filtering1=r(i:i+(app.filterbound1-1),j:j+(app.filterbound2-1)).*filter;
%                     finak(i,j)=sum(filtering1(:));
%                     filtering2=g(i:i+(app.filterbound1-1),j:j+(app.filterbound2-1)).*filter;
%                     final2(i,j)=sum(filtering2(:));
%                     filtering3=b(i:i+(app.filterbound1-1),j:j+(app.filterbound2-1)).*filter;
%                     final3(i,j)=sum(filtering3(:));
%                 end
%             end
%             
%             for x=1:size(r,1)
%                 for y=1:size(r,2)
%                  if finak(x,y)==0
%                        finak(x,y)=r(x,y);         
%                  end
%                  if final2(x,y)==0
%                       final2(x,y)=g(x,y);
%                  end
%                  if final3(x,y)==0
%                      final3(x,y)=b(x,y);
%                  end
%                 end
%             end
%             
%              
%             end
%             app.image2=cat(3,finak,final2,final3);
%             imshow(app.image2,'Parent',app.UIAxes)
            
       elseif change==2
      if app.c==1     
           a=app.filterbound1;
           a2=app.filterbound2;
           [s1,s2]=size(app.image1);
           r=im2double(app.image1);
           filter=ones(a,a2);
           filter=double(filter/sum(filter(:)));
           finak=zeros((size(r)));
           final2=zeros((size(r)));
           final3=zeros((size(r)));
           endpoint=size(r,1);
           endpoint2=size(r,2);
           extra=rem(s1,a);
           extra2=rem(s2,a2);
        for i=1:a:endpoint-extra
           for j=1:a2:endpoint2-extra2
           
            filtering1=r(i:i+(a-1),j:j+(a2-1)).*filter;
            for z=1:a
                for z2=1:a2
                  finak(i+z-1,j+z2-1)=sum(filtering1(:));
                end
            end
        
            end
        end
    a3=extra;
    a4=a2;
    filter2=ones(a3,a4);
    filter2=double(filter2/sum(filter2(:)));
    if extra~=0
     if extra~=1
        for i=endpoint-extra:a3:endpoint-a3+1
            for j=1:a4:endpoint2-a4+1
                filtering1=r(i:i+(a3-1),j:j+(a4-1)).*filter2;
            for z=1:a3
                for z2=1:a4
                  finak(i+z-1,j+z2-1)=sum(filtering1(:));
                end
            end
           end
       end
    end 
           
    end
     a5=a;
    a6=extra2;
    filter3=ones(a5,a6);
    filter3=double(filter3/sum(filter3(:)));
    
    if extra2~=0
       if extra2~=1
           for i=1:a5:endpoint-a5+1
            for j=endpoint2-extra2:a6:endpoint2-a6+1
                filtering1=r(i:i+(a5-1),j:j+(a6-1)).*filter3;
            for z=1:a5
                for z2=1:a6
                  finak(i+z-1,j+z2-1)=sum(filtering1(:));
                end
            end
         
            end
           
                
           end
       end
    end
     if extra~=0 && extra2~=0
         if extra~=1 && extra2~=1
            for i=endpoint-extra+1:extra:endpoint
                for j=endpoint2-extra2+1:extra2:endpoint2
                    a=extra;
                    a2=extra2;
                    filter=ones(a,a2);
                    filter=double(filter/sum(filter(:)));
    
                    filtering1=r(i:i+(a-1),j:j+(a2-1)).*filter;
            for z=1:a
                for z2=1:a2
                  finak(i+z-1,j+z2-1)=sum(filtering1(:));
                end
            end
                    
                end
            end
         end
     end
     app.image2=finak;
     imshow(app.image2,'Parent',app.UIAxes)
    
    
   end
      if app.c==3
                a=app.filterbound1;
                a2=app.filterbound2;
                [s1,s2,~]=size(app.image1);
            r=im2double(app.image1(:,:,1));
            g=im2double(app.image1(:,:,2));
            b=im2double(app.image1(:,:,3));
               filter=ones(a,a2);
    filter=double(filter/sum(filter(:)));
    finak=zeros((size(r)));
    final2=zeros((size(r)));
    final3=zeros((size(r)));
    endpoint=size(r,1);
    endpoint2=size(r,2);
    extra=rem(s1,a);
    extra2=rem(s2,a2);
    for i=1:a:endpoint-extra
        for j=1:a2:endpoint2-extra2
           
            filtering1=r(i:i+(a-1),j:j+(a2-1)).*filter;
            for z=1:a
                for z2=1:a2
                  finak(i+z-1,j+z2-1)=sum(filtering1(:));
                end
            end
            filtering2=g(i:i+(a-1),j:j+(a2-1)).*filter;
            for z=1:a
                for z2=1:a2
                   final2(i+z-1,j+z2-1)=sum(filtering2(:));
                end
            end
            filtering3=b(i:i+(a-1),j:j+(a2-1)).*filter;
            for z=1:a
                for z2=1:a2
                  final3(i+z-1,j+z2-1)=sum(filtering3(:));
                end
            end
        end
    end
    a3=extra;
    a4=a2;
    filter2=ones(a3,a4);
    filter2=double(filter2/sum(filter2(:)));
    if extra~=0
       if extra~=1
           for i=endpoint-extra:a3:endpoint-a3+1
            for j=1:a4:endpoint2-a4+1
                filtering1=r(i:i+(a3-1),j:j+(a4-1)).*filter2;
            for z=1:a3
                for z2=1:a4
                  finak(i+z-1,j+z2-1)=sum(filtering1(:));
                end
            end
            filtering2=g(i:i+(a3-1),j:j+(a4-1)).*filter2;
            for z=1:a3
                for z2=1:a4
                   final2(i+z-1,j+z2-1)=sum(filtering2(:));
                end
            end
            filtering3=b(i:i+(a3-1),j:j+(a4-1)).*filter2;
            for z=1:a3
                for z2=1:a4
                  final3(i+z-1,j+z2-1)=sum(filtering3(:));
                end
            end
            end
           
                
           end
       end
    end


    a5=a;
    a6=extra2;
    filter3=ones(a5,a6);
    filter3=double(filter3/sum(filter3(:)));
    
    if extra2~=0
       if extra2~=1
           for i=1:a5:endpoint-a5+1
            for j=endpoint2-extra2:a6:endpoint2-a6+1
                filtering1=r(i:i+(a5-1),j:j+(a6-1)).*filter3;
            for z=1:a5
                for z2=1:a6
                  finak(i+z-1,j+z2-1)=sum(filtering1(:));
                end
            end
            filtering2=g(i:i+(a5-1),j:j+(a6-1)).*filter3;
            for z=1:a5
                for z2=1:a6
                   final2(i+z-1,j+z2-1)=sum(filtering2(:));
                end
            end
            filtering3=b(i:i+(a5-1),j:j+(a6-1)).*filter3;
            for z=1:a5
                for z2=1:a6
                  final3(i+z-1,j+z2-1)=sum(filtering3(:));
                end
            end
            end
           
                
           end
       end
    end
     if extra~=0 && extra2~=0
         if extra~=1 && extra2~=1
            for i=endpoint-extra+1:extra:endpoint
                for j=endpoint2-extra2+1:extra2:endpoint2
                    a=extra;
                    a2=extra2;
                    filter=ones(a,a2);
                    filter=double(filter/sum(filter(:)));
    
                    filtering1=r(i:i+(a-1),j:j+(a2-1)).*filter;
            for z=1:a
                for z2=1:a2
                  finak(i+z-1,j+z2-1)=sum(filtering1(:));
                end
            end
            filtering2=g(i:i+(a-1),j:j+(a2-1)).*filter;
            for z=1:a
                for z2=1:a2
                   final2(i+z-1,j+z2-1)=sum(filtering2(:));
                end
            end
            filtering3=b(i:i+(a-1),j:j+(a2-1)).*filter;
            for z=1:a
                for z2=1:a2
                  final3(i+z-1,j+z2-1)=sum(filtering3(:));
                end
            end
                    
                end
            end
         end
     end
     app.image2=cat(3,finak,final2,final3);
     imshow(app.image2,'Parent',app.UIAxes)
      end
          elseif change==3
              if app.c==1
                  o=app.image2;
                  %app.image2=app.image1;
                  [m,n]=size(app.image2);
                minp=min(min(app.image2));
                maxp=max(max(app.image2));
                for i=1:m
                    for j=1:n
                        o(i,j)=((250/(maxp-minp))*(app.image1(i,j)-minp));
                    end
                end
                app.image2=uint8(app.image2);
                imshow(app.image2,'Parent',app.UIAxes)
              end
              if app.c==3
               r=double(app.image2(:,:,1));
                g=double(app.image2(:,:,2));
                b=double(app.image2(:,:,3));
                o=r;
                o2=g;
                o3=b;
                [m,n]=size(r);
                minp=min(min(r));
                maxp=max(max(r));
                minp2=min(min(g));
                maxp2=max(max(g));
                minp3=min(min(b));
                maxp3=max(max(b));
                for i=1:m
                    for j=1:n
                        o(i,j)=((250/(maxp-minp))*(r(i,j)-minp));
                        o2(i,j)=((250/(maxp2-minp2))*(g(i,j)-minp2));
                        o3(i,j)=((250/(maxp3-minp3))*(b(i,j)-minp3));
                    end
                end
                app.image2=uint8(cat(3,o,o2,o3));
                imshow(app.image2,'Parent',app.UIAxes)
              end
             elseif change==4
                 if app.c==3
                r=double(app.image2(:,:,1));
                g=double(app.image2(:,:,2));
                b=double(app.image2(:,:,3));
                b1=size(r);
                 % Loop for Getting the Histogram of the image
                hist1 = zeros(1,256);
                hist2 = zeros(1,256);
                hist3 = zeros(1,256);
                for i=1:b1(1)
                    for j=1:b1(2)
                        for k=0:255
                            if r(i,j)==k
                                hist1(k+1)=hist1(k+1)+1;
                            end
                            if g(i,j)==k
                                hist2(k+1)=hist2(k+1)+1;
                            end
                            if b(i,j)==k
                                hist3(k+1)=hist3(k+1)+1;
                            end
                        end
                    end
                end
                %Generating PDF out of histogram by diving by total no. of pixels
                pdf=(1/(b1(1)*b1(2)))*hist1;
                pdf2=(1/(b1(1)*b1(2)))*hist2;
                pdf3=(1/(b1(1)*b1(2)))*hist3;
                %Generating CDF out of PDF
                cdf = zeros(1,256);
                cdf2 = zeros(1,256);
                cdf3 = zeros(1,256);
                cdf(1)=pdf(1);
                cdf2(1)=pdf2(1);
                cdf3(1)=pdf3(1);
                for i=2:256
                    
                    cdf(i)=cdf(i-1)+pdf(i);
                    cdf2(i)=cdf2(i-1)+pdf2(i);
                    cdf3(i)=cdf3(i-1)+pdf3(i);
                end
                cdf = round(255*cdf);
                 cdf2 = round(255*cdf2);
                  cdf3 = round(255*cdf3);
                ep = zeros(b1);
                 ep2 = zeros(b1);
                  ep3 = zeros(b1);
                for i=1:b1(1)                                        %loop tracing the rows of image
                    for j=1:b1(2)                                    %loop tracing thes columns of image
                        t=(r(i,j)+1);                               %pixel values in image
                        ep(i,j)=cdf(t);                             %Making the ouput image using cdf as the transformation function
                        t2=(g(i,j)+1);                               %pixel values in image
                        ep2(i,j)=cdf2(t2);  
                        t3=(b(i,j)+1);                               %pixel values in image
                        ep3(i,j)=cdf3(t3);  
                    end                                             
                end
                app.image2=uint8(cat(3,ep,ep2,ep3));
                imshow(app.image2,'Parent',app.UIAxes)
                 end
                 if app.c==1
                a=app.image2;
                
                b=size(a);
                a=double(a);
                
                % Loop for Getting the Histogram of the image
                hist1 = zeros(1,256);
                for i=1:b(1)
                    for j=1:b(2)
                        for k=0:255
                            if a(i,j)==k
                                hist1(k+1)=hist1(k+1)+1;
                            end
                        end
                    end
                end
                
                %Generating PDF out of histogram by diving by total no. of pixels
                pdf=(1/(b(1)*b(2)))*hist1;
                
                %Generating CDF out of PDF
                cdf = zeros(1,256);
                cdf(1)=pdf(1);
                for i=2:256
                    
                    cdf(i)=cdf(i-1)+pdf(i);
                end
                cdf = round(255*cdf);
                
                ep = zeros(b);
                for i=1:b(1)                                        %loop tracing the rows of image
                    for j=1:b(2)                                    %loop tracing thes columns of image
                        t=(a(i,j)+1);                               %pixel values in image
                        ep(i,j)=cdf(t);                             %Making the ouput image using cdf as the transformation function
                    end                                             
                end
                
                % Loop for Getting the Histogram of the image
                hist2 = zeros(1,256);
                for i=1:b(1)
                    for j=1:b(2)
                        for k=0:255
                            if ep(i,j)==k
                                hist2(k+1)=hist2(k+1)+1;
                            end
                        end
                    end
                end
                app.image2=uint8(ep);
                imshow(app.image2,'Parent',app.UIAxes)
                 end
                 
          elseif change==5
              if app.c==1
                img=app.image2;
                f1=app.filterbound1;
                f2=app.filterbound2;
                mf=ones(f1,f2);
                output=zeros(size(img)-(f1-1));
                for i=1:size(img,1)-(f1-1)
                    for j=1:size(img,2)-(f2-1)
                    temp1=double(img(i:i+(f1-1),j:j+(f2-1))).*mf;
                    output(i,j)=median(temp1(:));
                    end
                end
                app.image2=(output);
                imshow(app.image2,'Parent',app.UIAxes)
              end
              if app.c==3
                r=double(app.image2(:,:,1));
                g=double(app.image2(:,:,2));
                b=double(app.image2(:,:,3));
                f1=app.filterbound1;
                f2=app.filterbound2;
                mf=ones(f1,f2);
                output=zeros(size(r)-(f1-1));
                output2=zeros(size(g)-(f1-1));
                output3=zeros(size(b)-(f1-1));
                for i=1:size(r,1)-(f1-1)
                    for j=1:size(r,2)-(f2-1)
                    temp1=double(r(i:i+(f1-1),j:j+(f2-1))).*mf;
                    output(i,j)=median(temp1(:));
                    temp2=double(g(i:i+(f1-1),j:j+(f2-1))).*mf;
                    output2(i,j)=median(temp2(:));
                    temp3=double(b(i:i+(f1-1),j:j+(f2-1))).*mf;
                    output3(i,j)=median(temp3(:));
                    end
                end
                app.image2=uint8(cat(3,output,output2,output3));
                imshow(app.image2,'Parent',app.UIAxes)
              end
          elseif change==6
              Kr=app.filterbound1;
              Kc=app.filterbound2;
              J=im2double(app.image2);
              app.image2=(Kr*Kc)./imfilter(1./(J+eps),ones(Kr,Kc),"replicate");
              imshow(app.image2,'Parent',app.UIAxes)
          elseif change==7
              Kr=app.filterbound1;
              Kc=app.filterbound2;
              J=im2double(app.image2);
              app.image2=exp(imfilter(log(J),ones(Kc,Kr),"replicate")).^(1/(Kr*Kc));
              imshow(app.image2,'Parent',app.UIAxes)
          elseif change==8
              J=im2double(app.image2);
              app.image2=wiener2(J,[app.filterbound1,app.filterbound2]);
              imshow(app.image2,'Parent',app.UIAxes)
           elseif change==0
              app.image2=app.image1;
              imshow(app.image2,'Parent',app.UIAxes)
         end
        end

        % Value changed function: WhiteSlider
        function WhiteSliderValueChanged(app, event)
            value = app.WhiteSlider.Value;
            value=round(value);
            if value>3
                app.BlackSlider.Limits=[0,value-3];
            end
            
        end

        % Value changed function: BlackSlider
        function BlackSliderValueChanged(app, event)
            value1 = app.BlackSlider.Value;
            value2= app.WhiteSlider.Value;
            [a,b,~]=size(app.image1);
            white_value=round(value2);
            black_value=round(value1);
            r=app.image1;
            formula=255/(white_value-black_value);
            f= @(x) ((x-white_value)*formula)+255;
            if app.c==1
             for i=1:a
                for j=1:b
                    
                     if black_value<r(i,j) && white_value>r(i,j)
                        new=double(r(i,j));
                        r(i,j)=uint8(f(new));
                     end
                     if r(i,j)<=black_value
                        r(i,j)=0;
                    end
                     if r(i,j)>=white_value
                        r(i,j)=255;
                     end
                end
            end
              app.image2=r;
            imshow(app.image2,'Parent',app.UIAxes)
            end
            
            if app.c==3
            
            
            for i=1:a
                for j=1:b
                    
                     if black_value<r(i,j,1) && white_value>r(i,j,1)
                        new=double(r(i,j,1));
                        r(i,j,1)=uint8(f(new));
                     end
                     if black_value<r(i,j,2) && white_value>r(i,j,2)
                        new=double(r(i,j,2));
                        r(i,j,2)=uint8(f(new));
                     end
                     if black_value<r(i,j,3) && white_value>r(i,j,3)
                        new=double(r(i,j,3));
                        r(i,j,3)=uint8(f(new));
                     end
                     if r(i,j,1)<=black_value
                        r(i,j,1)=0;
                    end
                     if r(i,j,1)>=white_value
                        r(i,j,1)=255;
                     end
                     if r(i,j,2)<=black_value
                        r(i,j,2)=0;
                    end
                     if r(i,j,2)>=white_value
                        r(i,j,2)=255;
                     end
                     if r(i,j,3)<=black_value
                        r(i,j,3)=0;
                    end
                     if r(i,j,3)>=white_value
                        r(i,j,3)=255;
                     end
                end
            end
            app.image2=r;
            imshow(app.image2,'Parent',app.UIAxes)
            end
        end

        % Button pushed function: ShowHistogramButton
        function ShowHistogramButtonPushed(app, event)
            imhist(app.image2)
        end

        % Button pushed function: HomeworksButton
        function HomeworksButtonPushed(app, event)
            %homeworks;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value==1
                [a,b]=size(app.image1);
                %Mean Filter
                f=3;
                filter=ones(f,f);
                filter=(filter)/sum(filter(:));
                filter=double(filter);
                array=padarray(app.image2,[1 1]);
                array=double(array);
                output1=zeros(a,b);
                for i=1:size(array,1)-f-1
                    for j=1:size(array,2)-f-1
                    temp1=array(i:i+f-1,j:j+f-1).*filter;
                    output1(i,j)=sum(temp1(:));
                    end
                end
                app.image2=uint8(output1);
                imshow(app.image2,'Parent',app.UIAxes)
            elseif value==2
                img=app.image2;
                mf=ones(3,3);
                output=zeros(size(img)-2);
                for i=1:size(img,1)-2
                    for j=1:size(img,2)-2
                    temp1=double(img(i:i+2,j:j+2)).*mf;
                    output(i,j)=median(temp1(:));
                    end
                end
                app.image2=uint8(output);
                imshow(app.image2,'Parent',app.UIAxes)
            elseif value==3
                A=app.image2;
                B=zeros(size(A));
                modifyA=padarray(A,[1 1]);
                x=[1:3]';
                y=[1:3]'; 
                for i= 1:size(modifyA,1)-2
                    for j=1:size(modifyA,2)-2
                       window=reshape(modifyA(i+x-1,j+y-1),[],1);
                       B(i,j)=max(window);
                    end
                end
                app.image2=uint8(B);
                imshow(app.image2,'Parent',app.UIAxes)
              elseif value==4
                A=app.image2;
                B=zeros(size(A));
                modifyA=padarray(A,[1 1]);
                x=[1:3]';
                y=[1:3]'; 
                for i= 1:size(modifyA,1)-2
                    for j=1:size(modifyA,2)-2
                       window=reshape(modifyA(i+x-1,j+y-1),[],1);
                       B(i,j)=min(window);
                    end
                end
                app.image2=uint8(B);
                imshow(app.image2,'Parent',app.UIAxes)
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if value==1
                img=app.image1;
                [a,b]=size(img);
                f=3;
                filter=[-1 -1 -1;-1 8 -1;-1 -1 -1];
                %filter=(filter)/sum(filter(:));
                %filter=double(filter);
                array=padarray(img,[1 1]);
                array=double(array);
                output1=zeros(a,b);
                output3=zeros(a,b);
                for i=1:size(array,1)-2
                    for j=1:size(array,2)-2
                    temp1=array(i:i+f-1,j:j+f-1).*filter;
                    output1(i,j)=sum(temp1(:));
                    end
                end
                 app.image2=uint8(output1);
                imshow(app.image2,'Parent',app.UIAxes)
            elseif value==2
                app.image_c=app.image1+app.image2;
                imshow(app.image_c,'Parent',app.UIAxes)
            elseif value==3
                img=app.image1;
                [a,b]=size(img);
                 array=padarray(img,[1 1]);
                array=double(array);
                 output2=zeros(a,b);
                for i=1:size(array,1)-2
                    for j=1:size(array,2)-2
                       Gx=((2*array(i+2,j+1)+array(i+2,j)+array(i+2,j+2))-(2*array(i,j+1)+array(i,j)+array(i,j+2)));
                       Gy=((2*array(i+1,j+2)+array(i,j+2)+array(i+2,j+2))-(2*array(i+1,j)+array(i,j)+array(i+2,j)));
                       output2(i,j)=sqrt(Gx.^2+Gy.^2);
                    end
                end
                image_d=uint8(output2);
                imshow(image_d,'Parent',app.UIAxes)
            elseif value==4
                img=app.image1;
                [a,b]=size(img);
                f=5;
                filter=ones(f,f);
                filter=(filter)/sum(filter(:));
                filter=double(filter);
                array=padarray(img,[1 1]);
                array=double(array);
                output1=zeros(a,b);
                for i=1:size(array,1)-f-1
                    for j=1:size(array,2)-f-1
                    temp1=array(i:i+f-1,j:j+f-1).*filter;
                    output1(i,j)=sum(temp1(:));
                    end
                end
                 app.image_e=uint8(output1);
                imshow(app.image_e,'Parent',app.UIAxes)
            elseif value==5
                app.image_f=medfilt2(double(app.image_e) .* double(app.image_c))/255;
                app.image_f=uint8(app.image_f);
                imshow(app.image_f,'Parent',app.UIAxes)
            elseif value==6
                app.image_g=app.image_f+app.image1;
                imshow(app.image_g,'Parent',app.UIAxes)
            elseif value==7
                gamma=exp(0.1);
                image_h=(double(app.image_g).^gamma);
                image_h=uint8(image_h);
                imshow(image_h,'Parent',app.UIAxes)
            end
        end

        % Button pushed function: FilteringButton
        function FilteringButtonPushed(app, event)
            
            % Saving the size of the input_image in pixels-
            % M : no of rows (height of the image)
            % N : no of columns (width of the image)
            [M, N] = size(app.image1);
            
            % Getting Fourier Transform of the input_image
            % using MATLAB library function fft2 (2D fast fourier transform)
            FT_img = fft2(double(app.image1));
            
            % Assign the order value
            n = 2; % one can change this value accordingly
            
            % Assign Cut-off Frequency
            D0 = 50; % one can change this value accordingly
            
            % Designing filter
            u = 0:(M-1);
            v = 0:(N-1);
            idx = find(u > M/2);
            u(idx) = u(idx) - M;
            idy = find(v > N/2);
            v(idy) = v(idy) - N;
            
            % MATLAB library function meshgrid(v, u) returns
            % 2D grid which contains the coordinates of vectors
            % v and u. Matrix V with each row is a copy of v
            % and matrix U with each column is a copy of u
            [V, U] = meshgrid(v, u);
            
            % Calculating Euclidean Distance
            D = sqrt(U.^2 + V.^2);
            
            % determining the filtering mask
            H = 1./(1 + (D0./D).^(2*n));
            
            % Convolution between the Fourier Transformed
            % image and the mask
            G = H.*FT_img;
            
            % Getting the resultant image by Inverse Fourier Transform
            % of the convoluted image using MATLAB library function
            % ifft2 (2D inverse fast fourier transform)
            output_image = real(ifft2(double(G)));
            imshow(output_image,'Parent',app.UIAxes)

        end

        % Button pushed function: Filtering2Button
        function Filtering2ButtonPushed(app, event)
            I=app.image1;
            Fshift      =   fftshift(fft2(double(I)));
            
            for row = 1 : 474
                for clm = 1 : 630
                    ycap = sqrt((row-250)^2 + (clm-300)^2);                                              %Detected the energy burst
                    if ycap < 50 A(row, clm) = 1;                                                        % 50x50 disk created 
                    else A(row, clm) = 0;
                    end
                end
            end
            
                                                                               %showed fourier magnitudes
            Gshift      =   A .* Fshift;                                                                %masked fourier burst points with fourier transformed image
            G           =   ifftshift(Gshift);
            g           =   abs(ifft2(G));
             image_h=uint8(g);
             imshow(image_h,'Parent',app.UIAxes)
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            [M, N]= size(app.image1);
            Gframe=double(app.image1);
            Gframe = Gframe - min(Gframe(:));
            Gframe = Gframe / max(Gframe(:));
            function R = noises(type, M, N, a, b)
                if nargin == 1
                   a = 0; b = 1;
                   M = 1; N = 1;
                elseif nargin == 3
                   a = 0; b = 1;
                end
                
                
                switch lower(type)
                case 'uniform'
                   R = a + (b - a)*rand(M, N);
                case 'gaussian'
                   R = a + b*randn(M, N);
                case 'lognormal'
                   if nargin <= 3
                      a = 1; b = 0.25;
                   end
                   R = a*exp(b*randn(M, N));
                case 'rayleigh'
                   R = a + (-b*log(1 - rand(M, N))).^0.5;
                case 'exponential'
                   if nargin <= 3
                      a = 1;
                   end
                   if a <= 0
                      error('Parameter a must be positive for exponential type.')
                   end
                   k = -1/a;
                   R = k*log(1 - rand(M, N));
                case 'erlang'
                   if nargin <= 3
                      a = 2; b = 5;
                   end
                   if (b ~= round(b) | b <= 0)
                      error('Param b must be a positive integer for Erlang.')
                   end
                   k = -1/a;
                   R = zeros(M, N);
                   for j = 1:b         
                      R = R + k*log(1 - rand(M, N));
                   end
                end
            end
             function R=saltpepper(M,N,a,b)
                    if nargin<=2
                        a=0.05;
                        b=0.05;
                    end
                    
                    R(1:M,1:N)=0.5;
                    X=rand(M,N);
                    c=find(X<=a);
                    R(c)=0;
                    u=a+b;
                    c=find(X>a & X<=u);
                    R(c)=1;
                end
            if value==1
                [M, N]= size(app.image1);
                R = saltpepper(M, N, 0.05, 0.05);
                c = find (R==0);
                gp = app.image1;
                gp(c) = 0;
                d = find (R==1);
                gp(d) = 255;
                app.image2=gp;
                imshow(app.image2,'Parent',app.UIAxes)
            elseif value==2
                [M, N]= size(app.image1);
                R = saltpepper(M, N, 0, 0.05);
                c = find (R==0);
                gp = app.image1;
                gp(c) = 0;
                d = find (R==1);
                gp(d) = 255;
                app.image2=gp;
                imshow(app.image2,'Parent',app.UIAxes)
            elseif value==3
                [M, N]= size(app.image1);
                R = saltpepper(M, N, 0.05, 0);
                c = find (R==0);
                gp = app.image1;
                gp(c) = 0;
                d = find (R==1);
                gp(d) = 255;
                app.image2=gp;
                imshow(app.image2,'Parent',app.UIAxes)
            elseif value==4
                n=0.1*noises('gaussian', M,N);
                app.image2=n+Gframe;
                imshow(app.image2,'Parent',app.UIAxes)
            elseif value==5
                n=0.3*noises('uniform', M,N);
                app.image2=n+Gframe;
                imshow(app.image2,'Parent',app.UIAxes)
            
            elseif value==6
                n=0.1*noises('erlang', M,N);
                app.image2=n+Gframe;
                imshow(app.image2,'Parent',app.UIAxes)
            elseif value==7
                n=0.3*noises('rayleigh', M,N);
                app.image2=n+Gframe;
                imshow(app.image2,'Parent',app.UIAxes)
              elseif value==8
                n=0.2*noises('exponential', M,N);
                app.image2=n+Gframe;
                imshow(app.image2,'Parent',app.UIAxes)
             elseif value==9
                n=0.3*noises('lognormal', M,N);
                app.image2=n+Gframe;
                imshow(app.image2,'Parent',app.UIAxes)
             elseif value==10
                s = size (app.image1); % It assigns the size of t to s.
                [x, y] = meshgrid (1: s (1), 1: s (2)); % x, y dimensional matrix.
                p = sin (x / 3 + y / 5) +1; % sins
                k = (im2double (app.image1) + p / 2) / 2;
                app.image2=k;
                imshow(app.image2,'Parent',app.UIAxes)
            end
        end

        % Callback function
        function PeriodicFilterButtonPushed(app, event)
            s = size (app.image2); % It assigns the size of t to s.
            [x, y] = meshgrid (1: s (1), 1: s (2)); % x, y dimensional matrix.
            p = sin (x / 3 + y / 5) +1; % sins
            k = (im2double (app.image2) + p / 2) / 2;

            p = 1 + sin (x + y / 1.5);
            tp = (double (k) / 128 + p) / 8;
            tp(88,:)=0;
            tp(171,:)=0;
            tp(:,156)=0;
            tp(:,102)=0;
            af = fftshift (fft2 (tp));
            s=ifftshift(ifft2(af));
            imshow(mat2gray((1+abs(s))),'Parent',app.UIAxes)
        end

        % Button pushed function: CalculateSNRButton
        function CalculateSNRButtonPushed(app, event)
            img=app.image2;
            img=double(img(:));
            ima=max(img(:));
            imi=min(img(:));
            ims=std(img(:));
            snr=10*log((ima-imi)./ims);
            app.EditField.Value=snr;
        end

        % Button pushed function: InverseFilterButton
        function InverseFilterButtonPushed(app, event)
            A=imread("image.jpeg");
            B=app.image2;
            B=im2double(B);
            crispfft = fft2(A);
            blurredfft = fft2(B);
            PSFfft = blurredfft ./ crispfft;
            PSF = ifftshift(ifft2(PSFfft));
            app.image2 = deconvwnr(B,PSF);

            imshow(mat2gray((1+abs(app.image2))),'Parent',app.UIAxes)
        
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_3.SelectedObject;
           if app.DiamondButton.Value==1
                app.Label_4.Visible="On";
                text1 = sprintf('R distance');
                app.Label_4.Text=text1;
                app.Spinner_4.Visible="On";
                app.DONEButton.Visible="On";
                 app.Label_3.Visible="Off";
                  app.Spinner_6.Visible="Off";
           elseif app.OctagonButton.Value==1
                app.Label_4.Visible="On";
                text1 = sprintf('R distance');
                app.Label_4.Text=text1;
                app.Spinner_4.Visible="On";
                app.DONEButton.Visible="On";
                 app.Label_3.Visible="Off";
                  app.Spinner_6.Visible="Off";
             elseif app.DiskButton.Value==1
                app.Label_4.Visible="On";
                text1 = sprintf('Radius');
                app.Label_4.Text=text1;
                app.Spinner_4.Visible="On";
                app.DONEButton.Visible="On";
                 app.Label_3.Visible="Off";
                  app.Spinner_6.Visible="Off";
            elseif app.MatrixwithonesButton.Value==1
                app.Label_4.Visible="On";
                text1 = sprintf('Rows');
                app.Label_4.Text=text1;
                app.Spinner_4.Visible="On";
                 app.Label_3.Visible="On";
                text2 = sprintf('Columns');
                app.Label_3.Text=text2;
                app.Spinner_6.Visible="On";
                app.DONEButton.Visible="On";
           elseif app.LinearButton.Value==1
                app.Label_4.Visible="On";
                text1 = sprintf('Length');
                app.Label_4.Text=text1;
                app.Spinner_4.Visible="On";
                 app.Label_3.Visible="On";
                text2 = sprintf('Degree');
                app.Label_3.Text=text2;
                app.Spinner_6.Visible="On";
                app.DONEButton.Visible="On";
           elseif app.RectangleButton.Value==1
                app.Label_4.Visible="On";
                text1 = sprintf('Long Edge');
                app.Label_4.Text=text1;
                app.Spinner_4.Visible="On";
                 app.Label_3.Visible="On";
                text2 = sprintf('Short Edge');
                app.Label_3.Text=text2;
                app.Spinner_6.Visible="On";
                app.DONEButton.Visible="On";
           elseif app.SquareButton.Value==1
                app.Label_4.Visible="On";
                text1 = sprintf('Edge Length');
                app.Label_4.Text=text1;
                app.Spinner_4.Visible="On";
                app.DONEButton.Visible="On";
                 app.Label_3.Visible="Off";
                  app.Spinner_6.Visible="Off";
            end
        end

        % Button pushed function: DONEButton
        function DONEButtonPushed(app, event)
             if app.OctagonButton.Value==1
                 r=app.Spinner_4.Value;
                 app.SE = strel("octagon",r);
             elseif app.DiamondButton.Value==1
                  r=app.Spinner_4.Value;
                 app.SE = strel("diamond",r);
              elseif app.DiskButton.Value==1
                  r=app.Spinner_4.Value;
                 app.SE = strel("disk",r);
               elseif app.DiskButton.Value==1
                  r=app.Spinner_4.Value;
                  col=app.Spinner_3.Value;
                 app.SE = strel(ones(r,col));
              elseif app.LinearButton.Value==1
                   len=app.Spinner_4.Value;
                  deg=app.Spinner_3.Value;
                  app.SE = strel("line",len,deg);
              elseif app.RectangleButton.Value==1
                   m=app.Spinner_4.Value;
                  n=app.Spinner_3.Value;
                  app.SE=strel("rectangle",[m n]);
              elseif app.SquareButton.Value==1
                    w=app.Spinner_4.Value;
                 app.SE = strel("square",w);
               elseif app.MatrixwithonesButton.Value==1
                  m=app.Spinner_4.Value;
                  n=app.Spinner_3.Value;
                  app.SE=strel(ones(m,n));
             end
        end

        % Value changed function: Knob_11
        function Knob_11ValueChanged(app, event)
            value = app.Knob_11.Value;
            se=app.SE;
            check=app.OriginalImageCheckBox.Value;
            if check==1
                if value==1
                    app.image2 = imopen(app.image1,se);
                    imshow(app.image2,'Parent',app.UIAxes)
                elseif value==2
                    app.image2 = imclose(app.image1,se);
                    imshow(app.image2,'Parent',app.UIAxes)
                elseif value==3
                    app.image2 = imerode(app.image1,se);
                    imshow(app.image2,'Parent',app.UIAxes)
                elseif value==4
                    app.image2 = imdilate(app.image1,se);
                    imshow(app.image2,'Parent',app.UIAxes)
                 elseif value==5
                    app.image2 = imtophat(app.image1,se);
                    imshow(app.image2,'Parent',app.UIAxes)
                 elseif value==6
                    app.image2 = imbothat(app.image1,se);
                    imshow(app.image2,'Parent',app.UIAxes)
                 elseif value==7
                    app.image2 = imdilate(app.image1, se) - imerode(app.image1, se);
                    imshow(app.image2,'Parent',app.UIAxes)
                elseif value==0
                    app.image2=app.image1;
                    imshow(app.image1,'Parent',app.UIAxes)
                end
            elseif check==0
                 if value==1
                    app.image2 = imopen(app.image2,se);
                    imshow(app.image2,'Parent',app.UIAxes)
                elseif value==2
                    app.image2 = imclose(app.image2,se);
                    imshow(app.image2,'Parent',app.UIAxes)
                 elseif value==3
                    app.image2 = imerode(app.image2,se);
                    imshow(app.image2,'Parent',app.UIAxes)
                elseif value==4
                    app.image2 = imdilate(app.image2,se);
                    imshow(app.image2,'Parent',app.UIAxes)
                elseif value==5
                    app.image2 = imtophat(app.image2,se);
                    imshow(app.image2,'Parent',app.UIAxes)
                 elseif value==6
                    app.image2 = imbothat(app.image2,se);
                    imshow(app.image2,'Parent',app.UIAxes)
                elseif value==7
                    app.image2 = imdilate(app.image2, se) - imerode(app.image2, se);
                    imshow(app.image2,'Parent',app.UIAxes)
                elseif value==0
                    app.image2=app.image1;
                    imshow(app.image2,'Parent',app.UIAxes)
                end
            end
        end

        % Button pushed function: OTSUThresholdButton
        function OTSUThresholdButtonPushed(app, event)
            [counts,x] = imhist(app.image2,32);
             T = otsuthresh(counts);
             app.image2 = imbinarize(app.image2,T);
             imshow(app.image2,'Parent',app.UIAxes)
        end

        % Button pushed function: RegionGrowingButton
        function RegionGrowingButtonPushed(app, event)
            app.image2=regiongrow3(app.image1,128);
            imshow(app.image2,'Parent',app.UIAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HOKAppUIFigure = uifigure('Visible', 'off');
            app.HOKAppUIFigure.Name = 'HOK App';
            app.HOKAppUIFigure.Icon = 'crown.png';
            app.GridLayout = uigridlayout(app.HOKAppUIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GenerateMatrixButton = uibutton(app.Panel, 'push');
            app.GenerateMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMatrixButtonPushed, true);
            app.GenerateMatrixButton.Text = 'Generate Matrix';
            app.RowofMatrixKnobLabel = uilabel(app.Panel);
            app.RowofMatrixKnobLabel.Text = 'Row of Matrix';
            app.RowofMatrixKnob = uiknob(app.Panel, 'discrete');
            app.RowofMatrixKnob.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30'};
            app.RowofMatrixKnob.ItemsData = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.RowofMatrixKnob.Value = 0;
            app.ColumnofMatrixKnobLabel = uilabel(app.Panel);
            app.ColumnofMatrixKnobLabel.Text = 'Column of Matrix';
            app.ColumnofMatrixKnob = uiknob(app.Panel, 'discrete');
            app.ColumnofMatrixKnob.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30'};
            app.ColumnofMatrixKnob.ItemsData = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.ColumnofMatrixKnob.Value = 0;
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.KnobsButton = uiradiobutton(app.ButtonGroup);
            app.KnobsButton.Text = 'Knobs';
            app.KnobsButton.Value = true;
            app.ManuelButton = uiradiobutton(app.ButtonGroup);
            app.ManuelButton.Text = 'Manuel';
            app.RowSpinnerLabel = uilabel(app.Panel);
            app.RowSpinnerLabel.Text = 'Row';
            app.RowSpinner = uispinner(app.Panel);
            app.ColumnSpinnerLabel = uilabel(app.Panel);
            app.ColumnSpinnerLabel.Text = 'Column';
            app.ColumnSpinner = uispinner(app.Panel);
            app.Knob_8 = uiknob(app.Panel, 'discrete');
            app.Knob_8.Items = {'Off', 'Mean Filter', 'Pixel', 'Hist. Stretching', 'Hist. Equalization', 'Median Filter', 'Harmonic Mean', 'Geometric Mean', 'Adaptive'};
            app.Knob_8.ItemsData = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.Knob_8.ValueChangedFcn = createCallbackFcn(app, @Knob_8ValueChanged2, true);
            app.Knob_8.Value = 0;
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Default Filters', 'Mean Filter', 'Median Filter', 'Max Filter', 'Min Filter'};
            app.DropDown.ItemsData = [0 1 2 3 4];
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 0;
            app.ShowHistogramButton = uibutton(app.Panel, 'push');
            app.ShowHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHistogramButtonPushed, true);
            app.ShowHistogramButton.Text = 'Show Histogram';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'giphy.gif';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.Switch = uiswitch(app.GridLayout, 'rocker');
            app.Switch.Items = {'Invisible', 'Visible'};
            app.Switch.ItemsData = [0 1];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 0;
            app.IMAGEPROCESSINGLabel = uilabel(app.GridLayout);
            app.IMAGEPROCESSINGLabel.Text = 'IMAGE PROCESSING';
            app.HalimezgeKABAK180403001Label = uilabel(app.GridLayout);
            app.HalimezgeKABAK180403001Label.Text = 'Halime Özge KABAK 180403001';
            app.EnterButton = uibutton(app.GridLayout, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.EditField2 = uieditfield(app.GridLayout, 'text');
            app.Lamp = uilamp(app.GridLayout);
            app.Panel_2 = uipanel(app.GridLayout);
            app.XValueSpinnerLabel = uilabel(app.Panel_2);
            app.XValueSpinnerLabel.Text = 'X-Value';
            app.XValueSpinner = uispinner(app.Panel_2);
            app.YValueSpinnerLabel = uilabel(app.Panel_2);
            app.YValueSpinnerLabel.Text = 'Y-Value';
            app.YValueSpinner = uispinner(app.Panel_2);
            app.reflect = uibutton(app.Panel_2, 'push');
            app.reflect.ButtonPushedFcn = createCallbackFcn(app, @reflectButtonPushed, true);
            app.reflect.Text = 'Reflect';
            app.CropButton = uibutton(app.Panel_2, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.ResizeButton = uibutton(app.Panel_2, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.ShiftButton = uibutton(app.Panel_2, 'push');
            app.ShiftButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftButtonPushed, true);
            app.ShiftButton.Text = 'Shift';
            app.ButtonGroup_2 = uibuttongroup(app.Panel_2);
            app.VerticalButton = uiradiobutton(app.ButtonGroup_2);
            app.VerticalButton.Text = 'Vertical';
            app.VerticalButton.Value = true;
            app.HorizontalButton = uiradiobutton(app.ButtonGroup_2);
            app.HorizontalButton.Text = 'Horizontal';
            app.Spinner_2 = uispinner(app.Panel_2);
            app.Spinner_3 = uispinner(app.Panel_2);
            app.WhiteSliderLabel = uilabel(app.Panel_2);
            app.WhiteSliderLabel.Text = 'White';
            app.WhiteSlider = uislider(app.Panel_2);
            app.WhiteSlider.Limits = [0 255];
            app.WhiteSlider.ValueChangedFcn = createCallbackFcn(app, @WhiteSliderValueChanged, true);
            app.BlackSliderLabel = uilabel(app.Panel_2);
            app.BlackSliderLabel.Text = 'Black';
            app.BlackSlider = uislider(app.Panel_2);
            app.BlackSlider.Limits = [0 255];
            app.BlackSlider.ValueChangedFcn = createCallbackFcn(app, @BlackSliderValueChanged, true);
            app.ListBox = uilistbox(app.Panel_2);
            app.ListBox.Items = {'No Extra Noise', 'Salt and Pepper', 'Only Salt', 'Only Pepper', 'Gaussian', 'Uniform', 'Erlang (Gamma)', 'Rayleigh', 'Exponential', 'Log Normal', 'Periodic Noise'};
            app.ListBox.ItemsData = [0 1 2 3 4 5 6 7 8 9 10];
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 0;
            app.CalculateSNRButton = uibutton(app.Panel_2, 'push');
            app.CalculateSNRButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSNRButtonPushed, true);
            app.CalculateSNRButton.Text = 'Calculate SNR';
            app.EditField = uieditfield(app.Panel_2, 'numeric');
            app.Knob_11 = uiknob(app.Panel_2, 'discrete');
            app.Knob_11.Items = {'Off', 'Opening', 'Closing', 'Erosion', 'Dilation', 'Top-Hat', 'Bottom-Hat', 'Gradient'};
            app.Knob_11.ItemsData = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.Knob_11.ValueChangedFcn = createCallbackFcn(app, @Knob_11ValueChanged, true);
            app.Knob_11.Value = 0;
            app.Knob_10 = uiknob(app.Panel_2, 'discrete');
            app.Knob_10.Items = {'Off', 'Gray', 'Black-White', 'Negative'};
            app.Knob_10.ItemsData = [0 1 2 3];
            app.Knob_10.ValueChangedFcn = createCallbackFcn(app, @Knob_6ValueChanged, true);
            app.Knob_10.Value = 0;
            app.Knob_9 = uiknob(app.Panel_2, 'discrete');
            app.Knob_9.Items = {'RGB', 'YIQ', 'HSI'};
            app.Knob_9.ItemsData = [0 1 2 3];
            app.Knob_9.ValueChangedFcn = createCallbackFcn(app, @Knob_9ValueChanged, true);
            app.Knob_9.Value = 0;
            app.ButtonGroup_3 = uibuttongroup(app.Panel_2);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.DiamondButton = uitogglebutton(app.ButtonGroup_3);
            app.DiamondButton.Icon = 'C:\Users\Ozge\Desktop\image processing hw\imagess\diamond.png';
            app.DiamondButton.Text = 'Diamond';
            app.DiamondButton.Value = true;
            app.DiskButton = uitogglebutton(app.ButtonGroup_3);
            app.DiskButton.Icon = 'C:\Users\Ozge\Desktop\image processing hw\imagess\bluray.png';
            app.DiskButton.Text = 'Disk';
            app.OctagonButton = uitogglebutton(app.ButtonGroup_3);
            app.OctagonButton.Icon = 'C:\Users\Ozge\Desktop\image processing hw\imagess\octagon.png';
            app.OctagonButton.Text = 'Octagon';
            app.LinearButton = uitogglebutton(app.ButtonGroup_3);
            app.LinearButton.Icon = 'C:\Users\Ozge\Desktop\image processing hw\imagess\line.png';
            app.LinearButton.Text = 'Linear';
            app.RectangleButton = uitogglebutton(app.ButtonGroup_3);
            app.RectangleButton.Icon = 'C:\Users\Ozge\Desktop\image processing hw\imagess\rectangle.png';
            app.RectangleButton.Text = 'Rectangle';
            app.SquareButton = uitogglebutton(app.ButtonGroup_3);
            app.SquareButton.Icon = 'C:\Users\Ozge\Desktop\image processing hw\imagess\square.png';
            app.SquareButton.Text = 'Square';
            app.MatrixwithonesButton = uitogglebutton(app.ButtonGroup_3);
            app.MatrixwithonesButton.Icon = 'C:\Users\Ozge\Desktop\image processing hw\imagess\matrix.png';
            app.MatrixwithonesButton.Text = 'Matrix with ones';
            app.Spinner_4 = uispinner(app.Panel_2);
            app.Spinner_4.Visible = 'off';
            app.Spinner_6 = uispinner(app.Panel_2);
            app.Spinner_6.Visible = 'off';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Visible = 'off';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Visible = 'off';
            app.DONEButton = uibutton(app.Panel_2, 'push');
            app.DONEButton.ButtonPushedFcn = createCallbackFcn(app, @DONEButtonPushed, true);
            app.DONEButton.Visible = 'off';
            app.DONEButton.Text = 'DONE';
            app.OriginalImageCheckBox = uicheckbox(app.Panel_2);
            app.OriginalImageCheckBox.Text = 'Original Image';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.HomeworksButton = uibutton(app.GridLayout, 'push');
            app.HomeworksButton.ButtonPushedFcn = createCallbackFcn(app, @HomeworksButtonPushed, true);
            app.HomeworksButton.Text = 'Homeworks';
            app.FilteringButton = uibutton(app.GridLayout, 'push');
            app.FilteringButton.ButtonPushedFcn = createCallbackFcn(app, @FilteringButtonPushed, true);
            app.FilteringButton.Text = 'Filtering';
            app.Filtering2Button = uibutton(app.GridLayout, 'push');
            app.Filtering2Button.ButtonPushedFcn = createCallbackFcn(app, @Filtering2ButtonPushed, true);
            app.Filtering2Button.Text = 'Filtering 2';
            app.DropDown_2 = uidropdown(app.GridLayout);
            app.DropDown_2.Items = {'X-Ray Images', 'Image_b', 'Image_c', 'Image_d', 'Image_e', 'Image_f', 'Image_g', 'Image_h'};
            app.DropDown_2.ItemsData = [0 1 2 3 4 5 6 7];
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 0;
            app.InverseFilterButton = uibutton(app.GridLayout, 'push');
            app.InverseFilterButton.ButtonPushedFcn = createCallbackFcn(app, @InverseFilterButtonPushed, true);
            app.InverseFilterButton.Text = 'Inverse Filter';
            app.OTSUThresholdButton = uibutton(app.GridLayout, 'push');
            app.OTSUThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @OTSUThresholdButtonPushed, true);
            app.OTSUThresholdButton.Text = 'OTSU Threshold';
            app.RegionGrowingButton = uibutton(app.GridLayout, 'push');
            app.RegionGrowingButton.ButtonPushedFcn = createCallbackFcn(app, @RegionGrowingButtonPushed, true);
            app.RegionGrowingButton.Text = 'Region Growing';
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.HOKAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HOKAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HOKAppUIFigure)
        end
    end
end